classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        resumeButton            matlab.ui.control.Button
        eStopButton             matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        TabGroup                matlab.ui.container.TabGroup
        MainTab                 matlab.ui.container.Tab
        ChooseyourcandiesPanel  matlab.ui.container.Panel
        BMinus                  matlab.ui.control.Button
        BPlus                   matlab.ui.control.Button
        GMinus                  matlab.ui.control.Button
        GPlus                   matlab.ui.control.Button
        RMinus                  matlab.ui.control.Button
        RPlus                   matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        OrderButton             matlab.ui.control.Button
        GreenAppleLabel         matlab.ui.control.Label
        Blueberry               matlab.ui.control.NumericEditField
        GreenApple              matlab.ui.control.NumericEditField
        Raspberry               matlab.ui.control.NumericEditField
        BlueberryLabel          matlab.ui.control.Label
        RaspberryLabel          matlab.ui.control.Label
        MaintainenceTab         matlab.ui.container.Tab
        PlaceBoxButton          matlab.ui.control.Button
        q7Spinner               matlab.ui.control.Spinner
        q7SpinnerLabel          matlab.ui.control.Label
        ActiveRobotSwitch       matlab.ui.control.Switch
        ActiveRobotSwitchLabel  matlab.ui.control.Label
        YEditField_2            matlab.ui.control.NumericEditField
        YEditField_2Label       matlab.ui.control.Label
        PEditField              matlab.ui.control.NumericEditField
        PEditFieldLabel         matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        REditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        EnterPoseLabel          matlab.ui.control.Label
        GoButton                matlab.ui.control.Button
        zEditField              matlab.ui.control.NumericEditField
        zEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        q6Spinner               matlab.ui.control.Spinner
        q6SpinnerLabel          matlab.ui.control.Label
        q5Spinner               matlab.ui.control.Spinner
        q5SpinnerLabel          matlab.ui.control.Label
        q4Spinner               matlab.ui.control.Spinner
        q4SpinnerLabel          matlab.ui.control.Label
        q3Spinner               matlab.ui.control.Spinner
        q3SpinnerLabel          matlab.ui.control.Label
        q2Spinner               matlab.ui.control.Spinner
        q2SpinnerLabel          matlab.ui.control.Label
        q1Spinner               matlab.ui.control.Spinner
        q1SpinnerLabel          matlab.ui.control.Label
        SweetSelectLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Program % The main program 
        active_robot % The robot being controlled in maintainence
    end
    
    properties (Access = public)
        s % Serial port
    end
    
    methods (Access = private)
        
        % Update transformation and RPY values in maintaincence for the
        % active robot
        function updateTrAndRPY(app, q)
            [tr, rpy] = app.Program.getTrAndRPY(q, app.active_robot);

            app.XEditField.Value = tr(1);
            app.YEditField.Value = tr(2);
            app.ZEditField.Value = tr(3);

            app.REditField.Value = rpy(1);
            app.PEditField.Value = rpy(2);
            app.YEditField_2.Value = rpy(3);

        end
        
        % Update all values in the GUI
        function refreshMaintainenceData(app)
            qlims = app.Program.getQLims(app.active_robot);
            q_values = app.Program.getQValues(app.active_robot);

            % Set Q limits
            app.q1Spinner.Limits = qlims(1, :);
            app.q2Spinner.Limits = qlims(2, :);
            app.q3Spinner.Limits = qlims(3, :);
            app.q4Spinner.Limits = qlims(4, :);
            app.q5Spinner.Limits = qlims(5, :);
            app.q6Spinner.Limits = qlims(6, :);

            % Set step size
            steps_to_max = 50;
            app.q1Spinner.Step = abs(qlims(1, 1) - qlims(1, 2))/steps_to_max;
            app.q2Spinner.Step = abs(qlims(2, 1) - qlims(2, 2))/steps_to_max;
            app.q3Spinner.Step = abs(qlims(3, 1) - qlims(3, 2))/steps_to_max;
            app.q4Spinner.Step = abs(qlims(4, 1) - qlims(4, 2))/steps_to_max;
            app.q5Spinner.Step = abs(qlims(5, 1) - qlims(5, 2))/steps_to_max;
            app.q6Spinner.Step = abs(qlims(6, 1) - qlims(6, 2))/steps_to_max;
            
            % Set q values
            app.q1Spinner.Value = q_values(1);
            app.q2Spinner.Value = q_values(2);
            app.q3Spinner.Value = q_values(3);
            app.q4Spinner.Value = q_values(4);
            app.q5Spinner.Value = q_values(5);
            app.q6Spinner.Value = q_values(6);
            
            app.updateTrAndRPY(q_values);

            % Disable q7 for UR3e
            app.q7Spinner.Visible = app.active_robot == "LBRiiwa";
            app.q7SpinnerLabel.Visible = app.q7Spinner.Visible;
            if app.q7Spinner.Visible
                app.q7Spinner.Limits = qlims(7, :);
                app.q7Spinner.Step = abs(qlims(7, 1) - qlims(7, 2))/steps_to_max;
                app.q7Spinner.Value = q_values(7);
            end
            
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            close all   % Clear previous plots
            app.TabGroup.Visible = "off";   % Hide main window until robots load
            app.resumeButton.Visible = "off";   % Hide resume button

            app.Program = MainProgram(0.5);        % Create instance of MainProgram controller

            app.eStopButton.BackgroundColor = [0.64,0.08,0.18];
            app.TabGroup.Visible = "on";    % Show main window after loading of the robots is done
            
            app.active_robot = app.ActiveRobotSwitch.Value;     % Set active robot in maintainence

            app.refreshMaintainenceData();

            % Setting up serial communication

            function readPushButton(timerObj, event)
                app = get(timerObj, 'UserData');
                if app.s.NumBytesAvailable > 0
                    read(app.s, app.s.NumBytesAvailable, "uint8");
                    app.pushedEStopButton();
                end
            end

            try
                % Open serial port
                app.s = serialport("/dev/cu.usbmodem1103", 115200, "Timeout", 1);
                configureTerminator(app.s, 0);


                % background task
                t = timer;
                t.TimerFcn = @readPushButton;
                t.Period = 0.5;
                t.ExecutionMode = 'fixedRate';
                t.UserData = app;
                start(t);
            catch
                warning('Problem setting up serial communication. Hardware E-Stop will not be used');
            end

            % Periodically check light curtain collision

            function lightCurtainUpdate(timerObj, event)
                app = get(timerObj, 'UserData');
                
                if app.Program.checkLightCurtainCollision()
                    app.Program.engageEStop();
                    app.resumeButton.BackgroundColor = [0.93,0.69,0.13]; % Set resume button to yellow if estop engaged
                    app.eStopButton.BackgroundColor = [1, 0, 0]; % Set to bright red when engaged

                    app.resumeButton.Visible = "off";

                end
            end

            t2 = timer;
            t2.TimerFcn = @lightCurtainUpdate;
            t2.Period = 0.01;
            t2.ExecutionMode = 'fixedRate';
            t2.UserData = app;
            start(t2);

            
        end

        % Button pushed function: ClearButton
        function clearCandySelection(app, event)
            app.Raspberry.Value = 0;
            app.GreenApple.Value = 0;
            app.Blueberry.Value = 0;
        end

        % Button pushed function: OrderButton
        function orderCandies(app, event)
            app.Program.putCandiesInBox(app.Raspberry.Value, app.GreenApple.Value, app.Blueberry.Value);
        end

        % Value changed function: q1Spinner, q2Spinner, q3Spinner, 
        % ...and 4 other components
        function updateQValues(app, event)
            % Used as an advanced teach to set q values
            q = [app.q1Spinner.Value, app.q2Spinner.Value, app.q3Spinner.Value, app.q4Spinner.Value, app.q5Spinner.Value, app.q6Spinner.Value];
            if app.active_robot == "LBRiiwa"
                q = [q app.q7Spinner.Value];
            end
            app.Program.setQValues(q, app.active_robot);
             
            app.refreshMaintainenceData();
        end

        % Value changed function: ActiveRobotSwitch
        function updateActiveRobot(app, event)
            app.active_robot = app.ActiveRobotSwitch.Value;

            app.refreshMaintainenceData();
        end

        % Button pushed function: eStopButton
        function pushedEStopButton(app, event)
            e_stop_status = app.Program.pressEStop();
            disp("E Stop PRESSED")

            if e_stop_status
                app.resumeButton.BackgroundColor = [0.93,0.69,0.13]; % Set resume button to yellow if estop engaged
                app.eStopButton.BackgroundColor = [1, 0, 0]; % Set to bright red when engaged
            else 
                app.resumeButton.BackgroundColor = [0.47,0.67,0.19]; % Set resume button to green if estop engaged
                app.eStopButton.BackgroundColor = [0.64,0.08,0.18]; % Set to dark red when disengaged
            end

            if app.Program.Run_Status
                app.resumeButton.Visible = "off";
            else
                app.resumeButton.Visible = "on";
            end
        end

        % Button pushed function: BMinus, BPlus, GMinus, GPlus, RMinus, 
        % ...and 1 other component
        function buttonCandyNo(app, event)
            switch event.Source
                case app.RPlus
                    app.Raspberry.Value = app.Raspberry.Value + 1;
                case app.RMinus
                    app.Raspberry.Value = app.Raspberry.Value - 1;

                case app.GPlus
                    app.GreenApple.Value = app.GreenApple.Value + 1;
                case app.GMinus
                    app.GreenApple.Value = app.GreenApple.Value - 1;

                case app.BPlus
                    app.Blueberry.Value = app.Blueberry.Value + 1;
                case app.BMinus
                    app.Blueberry.Value = app.Blueberry.Value - 1;
            end

        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)

            run_status = app.Program.resumeOperation();
            if run_status
                app.resumeButton.Visible = "off";
                app.Program.putCandiesInBox(app.Raspberry.Value, app.GreenApple.Value, app.Blueberry.Value);
            else
                app.resumeButton.Visible = "on";
            end

        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)
           % Use app.TabGroup.SelectedTab 
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Used as an advanced teach to set q values
            xValue = app.xEditField.Value;
            yValue = app.yEditField.Value;
            zValue = app.zEditField.Value;
            disp(["VALUES"])
            disp([xValue, yValue, zValue])
            if app.active_robot == "LBRiiwa"
                app.Program.LBRiiwa.moveWithGivenPose(xValue, yValue, zValue);
            else
                % app.Program.UR3e.moveWithGivenPose(str2num(xValue), str2num(yValueZ), str2num(zValue));
                app.Program.UR3e.moveWithGivenPose(xValue, yValue, zValue);
            end
            % app.Program.setQValues(q, app.active_robot);
             
            app.refreshMaintainenceData();
        end

        % Button pushed function: PlaceBoxButton
        function PlaceBoxButtonPushed(app, event)
            obj.SphereR = 0.12;
            obj.Sphere = [0, 0.5, 0.75];
            [x, y, z] = ellipsoid(obj.Sphere(1), obj.Sphere(2), obj.Sphere(3), obj.SphereR,obj.SphereR, obj.SphereR);
            surf(x, y, z);
            
            app.Program.addObstacle(obj.Sphere, obj.SphereR);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SweetSelectLabel = uilabel(app.UIFigure);
            app.SweetSelectLabel.Text = 'Sweet Select';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChooseyourcandiesPanel = uipanel(app.MainTab);
            app.ChooseyourcandiesPanel.Title = 'Choose your candies';
            app.RaspberryLabel = uilabel(app.ChooseyourcandiesPanel);
            app.RaspberryLabel.Text = 'Raspberry';
            app.BlueberryLabel = uilabel(app.ChooseyourcandiesPanel);
            app.BlueberryLabel.Text = 'Blueberry';
            app.Raspberry = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.Raspberry.Limits = [0 Inf];
            app.GreenApple = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.GreenApple.Limits = [0 Inf];
            app.Blueberry = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.Blueberry.Limits = [0 Inf];
            app.GreenAppleLabel = uilabel(app.ChooseyourcandiesPanel);
            app.GreenAppleLabel.Text = 'Green Apple';
            app.OrderButton = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.OrderButton.ButtonPushedFcn = createCallbackFcn(app, @orderCandies, true);
            app.OrderButton.Text = 'Order';
            app.ClearButton = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearCandySelection, true);
            app.ClearButton.Text = 'Clear';
            app.RPlus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.RPlus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.RPlus.Text = '+';
            app.RMinus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.RMinus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.RMinus.Text = '-';
            app.GPlus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.GPlus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.GPlus.Text = '+';
            app.GMinus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.GMinus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.GMinus.Text = '-';
            app.BPlus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.BPlus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.BPlus.Text = '+';
            app.BMinus = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.BMinus.ButtonPushedFcn = createCallbackFcn(app, @buttonCandyNo, true);
            app.BMinus.Text = '-';
            app.MaintainenceTab = uitab(app.TabGroup);
            app.MaintainenceTab.Title = 'Maintainence';
            app.q1SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.MaintainenceTab);
            app.q1Spinner.Step = 0.01;
            app.q1Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q2SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.MaintainenceTab);
            app.q2Spinner.Step = 0.01;
            app.q2Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q3SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.MaintainenceTab);
            app.q3Spinner.Step = 0.01;
            app.q3Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q4SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.MaintainenceTab);
            app.q4Spinner.Step = 0.01;
            app.q4Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q5SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.MaintainenceTab);
            app.q5Spinner.Step = 0.01;
            app.q5Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q6SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.MaintainenceTab);
            app.q6Spinner.Step = 0.01;
            app.q6Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.xEditFieldLabel = uilabel(app.MaintainenceTab);
            app.xEditFieldLabel.Text = 'x:';
            app.xEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.MaintainenceTab);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.MaintainenceTab);
            app.zEditFieldLabel.Text = 'z:';
            app.zEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.GoButton = uibutton(app.MaintainenceTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.EnterPoseLabel = uilabel(app.MaintainenceTab);
            app.EnterPoseLabel.Text = 'Enter Pose';
            app.XEditFieldLabel = uilabel(app.MaintainenceTab);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.MaintainenceTab);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.MaintainenceTab);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.ZEditField.Editable = 'off';
            app.REditFieldLabel = uilabel(app.MaintainenceTab);
            app.REditFieldLabel.Text = 'R:';
            app.REditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.REditField.Editable = 'off';
            app.PEditFieldLabel = uilabel(app.MaintainenceTab);
            app.PEditFieldLabel.Text = 'P:';
            app.PEditField = uieditfield(app.MaintainenceTab, 'numeric');
            app.PEditField.Editable = 'off';
            app.YEditField_2Label = uilabel(app.MaintainenceTab);
            app.YEditField_2Label.Text = 'Y:';
            app.YEditField_2 = uieditfield(app.MaintainenceTab, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.ActiveRobotSwitchLabel = uilabel(app.MaintainenceTab);
            app.ActiveRobotSwitchLabel.Text = 'Active Robot';
            app.ActiveRobotSwitch = uiswitch(app.MaintainenceTab, 'slider');
            app.ActiveRobotSwitch.Items = {'UR3e', 'LBRiiwa'};
            app.ActiveRobotSwitch.ValueChangedFcn = createCallbackFcn(app, @updateActiveRobot, true);
            app.ActiveRobotSwitch.Value = 'UR3e';
            app.q7SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q7SpinnerLabel.Text = 'q7';
            app.q7Spinner = uispinner(app.MaintainenceTab);
            app.q7Spinner.Step = 0.01;
            app.q7Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.PlaceBoxButton = uibutton(app.MaintainenceTab, 'push');
            app.PlaceBoxButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceBoxButtonPushed, true);
            app.PlaceBoxButton.Text = 'Place Box';
            app.Lamp = uilamp(app.UIFigure);
            app.eStopButton = uibutton(app.UIFigure, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @pushedEStopButton, true);
            app.eStopButton.Text = 'EMERGENCY STOP';
            app.resumeButton = uibutton(app.UIFigure, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Visible = 'off';
            app.resumeButton.Text = 'RESUME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end